classdef Authentication_And_Score < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CenterPanel               matlab.ui.container.Panel
        GridLayout                matlab.ui.container.GridLayout
        MatchEditField            matlab.ui.control.EditField
        MatchEditFieldLabel       matlab.ui.control.Label
        AuthenticateButton        matlab.ui.control.Button
        LoadEnrolledImageButton   matlab.ui.control.Button
        ImagePanel                matlab.ui.container.Panel
        ImagePanel_GridLayout     matlab.ui.container.GridLayout
        EnrolledImage             matlab.ui.control.UIAxes
        AuthenticationImage       matlab.ui.control.UIAxes
        AuthenticationScoreLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        preproc_enrol % other window
        var % enrolled mat file
        e_minutiae % minutiae of enrolled image
        e_im % minutiae overlaid on to enrolled fingerprint
        a_minutiae % minutiae of authenticated image
        a_reduced_minutiae % reduced authentication minutiae
        a_combined % minutiae overlaid on to authenticated fingerprint
        a_binim % authenticate binary image
    end
    
    methods (Access = private)
        
        function [S] = match(app, M1, M2)
            M1 = M1(M1(:, 3) < 5, :);
            M2 = M2(M2(:, 3) < 5, :);    
            count1 = size(M1, 1); count2 = size(M2, 1); 
            S=0;            % best similarity score
            for i = 1:count1
                T1 = transform(app, M1, i);
                for j = 1:count2
                    if M1(i, 3) == M2(j, 3)
                        T2 = transform(app, M2, j);
                        for a = -5:5                      % alpha
                            T3 = transform2(app, T2, a*pi/180);
                            sm = score(app, T1, T3);
                            if S < sm
                                S = sm;
                            end                
                        end
                    end
                end
            end
        end

        function [T] = transform(~, M, i)
            Count = size(M, 1);
            XRef = M(i, 1); YRef = M(i, 2); ThRef = M(i, 4);
            T = zeros(Count, 4);
            % transformation matrix
            R = [cos(ThRef) sin(ThRef) 0; - sin(ThRef) cos(ThRef) 0; 0 0 1];
            for i = 1:Count
                B = [M(i, 1) - XRef; M(i, 2) - YRef; M(i, 4)-ThRef];
                T(i, 1:3) = R * B;
                T(i, 4) = M(i, 3);
            end
        end

        function [Tnew] = transform2(~, T, alpha)
            Count = size(T, 1);
            Tnew = zeros(Count, 4);
            R = [cos(alpha) sin(alpha) 0 0; -sin(alpha) cos(alpha) 0 0;...
              0 0 1 0; 0 0 0 1];             % transformation matrix
            for i = 1:Count
                B = T(i, :) - [0 0 alpha 0];
                Tnew(i, :) = R  *B';
            end
        end

        function [sm] = score(~, T1, T2)
            Count1 = size(T1, 1); Count2 = size(T2, 1); n = 0;
            T = 15;  % threshold for distance
            TT = 14; % threshold for theta
            for i = 1:Count1
                Found = 0; j = 1;
                while (Found == 0) && (j <= Count2)
                    dx = (T1(i, 1) - T2(j, 1));
                    dy = (T1(i, 2) - T2(j, 2));
                    % euclidean distance between T1(i) & T2(i)
                    d = sqrt(dx ^ 2 + dy ^ 2);
                    if d < T
                        DTheta = abs(T1(i, 3) - T2(j, 3)) * 180 / pi;
                        DTheta = min(DTheta, 360 - DTheta);
                        if DTheta < TT
                            n = n + 1;        % increase score
                            Found = 1;
                        end
                    end
                    j = j + 1;
                end
            end
            sm = sqrt(n ^ 2 / (Count1 * Count2));       % Similarity Index
        end

    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, otherapp, combined)
            [app.preproc_enrol] = otherapp;
            [app.e_im] = combined;
        end

        % Button pushed function: LoadEnrolledImageButton
        function LoadEnrolledImageButtonPushed(app, event)
            [app.var] = load('Enrolled_Fingerprint.mat');
            [app.e_minutiae] = app.var.reduced_minutiae;
            imshow(app.e_im, 'Parent', app.EnrolledImage);
        end

        % Button pushed function: AuthenticateButton
        function AuthenticateButtonPushed(app, event)
            [Filename, Pathname] = uigetfile('*.tif', 'File Selector');
            [name] = strcat(Pathname, Filename);
            [im] = imread(name);

            [app.a_minutiae, app.a_combined, app.a_binim] = ...
            authentication(app.preproc_enrol, im);
            imshow(app.a_combined, 'Parent', app.AuthenticationImage);

            fprintf('Authentication Enhancement - \n');
            enhancement_performance(app.preproc_enrol, app.a_binim, im);
            
            fprintf('\nAuthentication Percent of variability:\n');
            [app.a_reduced_minutiae] = feature_reduction( ...
                app.preproc_enrol, app.a_minutiae);

            [score] = match(app, app.e_minutiae, app.a_reduced_minutiae);
            
            [score] = score * 100;
            [percent] = '%.1f%%';
            [match_score] = sprintf(percent, score);

            [app.MatchEditField.Value] = match_score;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AuthenticationScoreLabel = uilabel(app.UIFigure);
            app.AuthenticationScoreLabel.Text = 'Authentication & Score';
            app.CenterPanel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.CenterPanel);
            app.ImagePanel = uipanel(app.GridLayout);
            app.ImagePanel_GridLayout = uigridlayout(app.ImagePanel);
            app.AuthenticationImage = uiaxes(app.ImagePanel_GridLayout);
            app.AuthenticationImage.XTick = [];
            app.AuthenticationImage.XTickLabel = '';
            app.AuthenticationImage.YTick = [];
            app.AuthenticationImage.ZTick = [];
            app.EnrolledImage = uiaxes(app.ImagePanel_GridLayout);
            app.EnrolledImage.XTick = [];
            app.EnrolledImage.XTickLabel = '';
            app.EnrolledImage.YTick = [];
            app.EnrolledImage.ZTick = [];
            app.LoadEnrolledImageButton = uibutton(app.GridLayout, 'push');
            app.LoadEnrolledImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEnrolledImageButtonPushed, true);
            app.LoadEnrolledImageButton.Text = 'Load Enrolled Image';
            app.AuthenticateButton = uibutton(app.GridLayout, 'push');
            app.AuthenticateButton.ButtonPushedFcn = createCallbackFcn(app, @AuthenticateButtonPushed, true);
            app.AuthenticateButton.Text = 'Authenticate';
            app.MatchEditFieldLabel = uilabel(app.GridLayout);
            app.MatchEditFieldLabel.Text = 'Match:';
            app.MatchEditField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Authentication_And_Score(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end