classdef Preprocessing_And_Enrollment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        LeftPanel_GridLayout          matlab.ui.container.GridLayout
        Label                         matlab.ui.control.Label
        EnrollmentPanel               matlab.ui.container.Panel
        Enrollment_GridLayout         matlab.ui.container.GridLayout
        EnrollButton                  matlab.ui.control.Button
        PreprocessingPanel            matlab.ui.container.Panel
        Preprocessing_GridLayout      matlab.ui.container.GridLayout
        SegmentationButton            matlab.ui.control.Button
        GradientButton                matlab.ui.control.Button
        OrientationButton             matlab.ui.control.Button
        DirectionMapButton            matlab.ui.control.Button
        RidgeFrequencyButton          matlab.ui.control.Button
        GaborFilteringButton          matlab.ui.control.Button
        BinarizationButton            matlab.ui.control.Button
        ThinningButton                matlab.ui.control.Button
        Label_1                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        FingerprinttoenhancePanel     matlab.ui.container.Panel
        Fingerprinttoenhance_GridLayout  matlab.ui.container.GridLayout
        SelectImageButton             matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        RightPanel_GridLayout         matlab.ui.container.GridLayout
        PreprocessingEnrollmentLabel  matlab.ui.control.Label
        FingerprintImage              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        im % fingeprint image
        mask % fingeprint segmented outline
        roi % region of interest
        gx % y axis gradient
        gy % x axis gradient
        dmask % direction image
        orientim % orientation image
        medianfreq % median of frequency
        newim % new gabor filtered image
        binim % binary image
        combined % minutiae overlaid on to skelim
        location % folder path
        auth_score % other window
        skelim % skeleton image
    end
    
    methods (Access = private)
        
        function [mask] = segment(~, im, blksze, thresh)
            % processes the im by applying the function fun to each 
            % distinct block of size blksze
            fun = @(block_struct) ...
            std2(block_struct.data) * ones(size(block_struct.data));
            
            StdDevIm = blockproc(im, [blksze blksze], fun);
            
            % if the im block is more than thresh the block is 1 else 0
            mask = StdDevIm > thresh;
        end

        function [gx, gy, gxmask, gymask] = ridgegradient(~, im, mask, ...
                hsize, hsigma)
            % taken from Roland Bruggman
            % returns a rotationally symmetric gaussian lowpass filter of 
            % size hsize with standard deviation hsigma
            h = fspecial('gaussian', hsize, hsigma);
            
            % returns the x and y components of the two-dimensional 
            % numerical gradient of matrix H
            [hx, hy] = gradient(h);
        
            % applies a finite impulse response filter to im according to 
            % coefficients in matrix hx and hy.
            gx = filter2(hx, im);
            gy = filter2(hy, im);
            
            % element wise multiplication of gx and mask to get segmented 
            % image
            gxmask = double(gx).*double(mask);
            gymask = double(gy).*double(mask);
        end

        function [dmask] = ridgeorient(~, hsize, hsigma, gx, gy, mask)
            % taken from Roland Bruggman
            % returns a rotationally symmetric gaussian lowpass filter of 
            % size hsize with standard deviation hsigma
            h = fspecial('gaussian', hsize, hsigma);
            
            % Local ridge orientation d (in radiant)
            gxy = gx.*gy;   
            gxy = 2*filter2(h, gxy);
        
            gxx = gx.^2;
            gxx = filter2(h, gxx);
        
            gyy = gy.^2;
            gyy = filter2(h, gyy);
        
            denom = sqrt((gxx - gyy).^2 + gxy.^2) + eps;
        
            sin2theta = gxy./denom; 
            sin2theta = filter2(h, sin2theta);
        
            cos2theta = (gxx-gyy)./denom;
            cos2theta = filter2(h, cos2theta);
            
            % the final smoothed orientation field
            d = pi/2 + atan2(sin2theta,cos2theta)/2;
            
            % element wise multiplication of d and mask to get segmented 
            % image
            dmask  = double(d).*double(mask);
        end

        function [im] = scalemap(~, im)
            % an image that uses the full range of colors
            h = imagesc(im);
            % get axes colormap
            cm = gray(256);
            % number of colors in colormap
            n = size(cm, 1);
            % intensity range
            c = linspace(h.Parent.CLim(1), h.Parent.CLim(2), n);
            % indexed image
            ind = reshape(interp1(c, 1:n, im(:), 'nearest'), size(im));
            
            % convert image and colormap map to RGB (truecolor) format
            im = ind2rgb(ind, cm);
        end
        
        function [x, y, u, v] = directionmap(~, d, s)
            % adaptation of Peter Koveski's plotridgeorient
            [r, c] = size(d);
            l = 0.8 * s; % length of lines
            
            % Subsample the directions
            sdir = d(s:s:r-s, s:s:c-s);
            xoff = l/2 * cos(sdir);
            yoff = l/2 * sin(sdir);
            
            % Determine placement of direction vectors
            [x, y] = meshgrid(s:s:c-s, s:s:r-s);
            x = x - xoff;
            y = y - yoff;
            
            % Orientation vectors
            u = xoff * 2;
            v = yoff * 2;
        end
        
        function [freq, medianfreq] = ridgefreq(app, im, mask, dmask, ...
                blksze, windsze, minWaveLength, maxWaveLength) 
            % taken from Peter Koveski
            [rows, cols] = size(im);
            freq = zeros(size(im));
            
            for r = 1:blksze:rows-blksze
                for c = 1:blksze:cols-blksze
                  blkim = im(r:r+blksze-1, c:c+blksze-1);   
                  blkor = dmask(r:r+blksze-1, c:c+blksze-1);       
                  
                  freq(r:r+blksze-1,c:c+blksze-1) =  ...
                      freqest(app, blkim, blkor, windsze, minWaveLength, ...
                      maxWaveLength);
                end
            end
        
            % mask out frequencies calculated for non ridge regions
            freq = freq.*mask;
            medianfreq = median(freq(freq>0)); 
        end
        
        function [freqim] =  freqest(~, im, dmask, windsze, ...
                minWaveLength, maxWaveLength)           
            [rows, ~] = size(im);
            % taken from Peter Koveski
            % find mean orientation within the block. this is done by 
            % averaging the sines and cosines of the doubled angles before 
            % reconstructing the angle again. This avoids wraparound 
            % problems at the origin.
            dmask = 2*dmask(:);    
            cosorient = mean(cos(dmask));
            sinorient = mean(sin(dmask));    
            orient = atan2(sinorient,cosorient)/2;
        
            % rotate the image block so that the ridges are vertical
            rotim = imrotate(im,orient/pi*180+90,'nearest', 'crop');
            
            % now crop the image so that the rotated image does not contain 
            % any invalid regions.  this prevents the projection down the 
            % columns from being mucked up.
            cropsze = fix(rows/sqrt(2)); offset = fix((rows-cropsze)/2);
            rotim = rotim(offset:offset+cropsze, offset:offset+cropsze);
        
            % sum down the columns to get a projection of the grey values 
            % down the ridges.
            proj = sum(rotim);
            
            % find peaks in projected grey values by performing a greyscale
            % dilation and then finding where the dilation equals the 
            % original values. 
            dilation = ordfilt2(proj, windsze, ones(1,windsze));
            maxpts = (dilation == proj) & (proj > mean(proj));
            maxind = find(maxpts);
        
            % determine the spatial frequency of the ridges by divinding 
            % the distance between the 1st and last peaks by the 
            % (No of peaks-1). if no peaks are detected, or the wavelength 
            % is outside the allowed bounds, the frequency image is set to 
            % 0
            if length(maxind) < 2
	        freqim = zeros(size(im));
            else
	        NoOfPeaks = length(maxind);
	        waveLength = (maxind(end) - maxind(1)) / (NoOfPeaks - 1);
	        if waveLength > minWaveLength && waveLength < maxWaveLength
	            freqim = 1 / waveLength * ones(size(im));
	        else
	            freqim = zeros(size(im));
	        end
            end
        end
        
        function newim = ridgefilter(~, im, orient, freq, kx, ky)
            % taken from Peter Koveski
            % fixed angle increment between filter orientations in degrees. 
            % this should divide evenly into 180
            angleInc = 3;
            
            im = double(im);
            [rows, cols] = size(im);
            newim = zeros(rows, cols);
            
            % find where there is valid frequency data.
            [validr, validc] = find(freq > 0);
            ind = sub2ind([rows,cols], validr, validc);
        
            % round the array of frequencies to the nearest 0.01 to reduce 
            % the number of distinct frequencies we have to deal with.
            freq(ind) = round(freq(ind) * 100) / 100;
            
            % generate an array of the distinct frequencies present in the 
            % array freq 
            unfreq = unique(freq(ind)); 
            
            % generate a table, given the frequency value multiplied by 100 
            % to obtain an integer index, returns the index within the 
            % unfreq array that it corresponds to
            freqindex = ones(100, 1);
            for k = 1:length(unfreq)
                freqindex(round(unfreq(k) * 100)) = k;
            end
            
            % generate filters corresponding to these distinct frequencies 
            % and orientations in 'angleInc' increments.
            filter = cell(length(unfreq), 180 / angleInc);
            sze = zeros(length(unfreq), 1);
            
            for k = 1:length(unfreq)
                sigmax = 1 / unfreq(k) * kx;
                sigmay = 1 / unfreq(k) * ky;
                
                sze(k) = round(3 * max(sigmax, sigmay));
                [x, y] = meshgrid(-sze(k):sze(k));
                reffilter = exp(-(x.^2 / sigmax ^ 2 + y.^2 / sigmay ^ 2) ...
                    /2).*cos(2 * pi * unfreq(k) * x);
        
                % generate rotated versions of the filter.  note 
                % orientation image provides orientation *along* the 
                % ridges, hence +90 degrees, and imrotate requires angles 
                % +ve anticlockwise, hence the minus sign.
                for o = 1:180 / angleInc
                    filter{k, o} = imrotate(reffilter, ...
                        -(o * angleInc + 90),'bilinear','crop'); 
                end
            end
            
            % find indices of matrix points greater than maxsze from the 
            % image boundary
            maxsze = sze(1);    
            finalind = find(validr > maxsze & validr < rows - maxsze & ...
                            validc > maxsze & validc < cols - maxsze);
            
            % convert orientation matrix values from radians to an index 
            % value that corresponds to round(degrees/angleInc)
            maxorientindex = round(180 / angleInc);
            orientindex = round(orient / pi * 180 / angleInc);
            i = find(orientindex < 1);   
            orientindex(i) = orientindex(i) + maxorientindex;
            i = find(orientindex > maxorientindex); 
            orientindex(i) = orientindex(i) - maxorientindex; 
        
            % Finally do the filtering
            for k = 1:length(finalind)
                r = validr(finalind(k));
                c = validc(finalind(k));
        
                % find filter corresponding to freq(r,c)
                filterindex = freqindex(round(freq(r, c) * 100));
                
                s = sze(filterindex);   
                newim(r, c) = sum(sum(im(r - s:r + s, c - s:c + s).* ...
                    filter {filterindex, orientindex(r, c)}));
            end
        end

        function [j, X, Y] = p(~, img, x, y, i)
            % taken from Vahid K. Alilou
            % get pixel value based on chart:
            %  4 | 3 | 2
            %  5 |   | 1
            %  6 | 7 | 8
            
            switch (i)
                case {1, 9}
                    Y = y;
                    X = x + 1;
                    j = img(y, x + 1);
                case 2
                    Y = y - 1;
                    X = x + 1;
                    j = img(y - 1, x + 1);
                case 3
                    Y = y - 1;
                    X = x;
                    j = img(y - 1, x);
                case 4
                    Y = y - 1;
                    X = x - 1;
                    j = img(y - 1, x - 1);
                case 5
                    Y = y;
                    X = x - 1;
                    j = img(y, x - 1);
                case 6
                    Y = y + 1;
                    X = x - 1; 
                    j = img(y + 1, x - 1);
                case 7
                    Y = y + 1;
                    X = x;
                    j = img(y + 1, x);
                case 8
                    Y = y + 1;
                    X = x + 1; 
                    j = img(y + 1, x + 1);
            end
        end

        function [res, progress, sx, sy, angle] = test_bifurcation(app, ...
                img, x, y, ~, core_x, core_y)
            % taken from Vahid K. Alilou
            % returns if the single ridge in a bifurcation is 
            % the going the same direction as the orientation (res = 3)
            % or in in the opposite (res = 2)
               progress = 1;
               path_len = 4;
               
               %1 - 8 position of 3x3 square around minutia
               pax = 0; pay = 0; pbx = 0; pby = 0; pcx = 0; pcy = 0;
               pao = 0; pbo = 0; pco = 0;
               
               for i = 1:8
                     [ta, xa, ya] = p(app, img, x, y, i);
                     [tb, ~, ~] = p(app, img, x, y, i+1);
                     if (ta > tb) 
                       if pao == 0
                          if i < 5
                             pao = 4 + i;
                          else
                             pao = mod(4 + i, 9) + 1;
                          end
                          pax = xa;
                          pay = ya;
                       else
                          if pbo == 0 
                             if i < 5
                                pbo = 4 + i;
                             else
                                pbo = mod(4 + i, 9) + 1;
                             end
                             pbx = xa;
                             pby = ya;
                          else
                             if i < 5 
                                pco = 4 + i;
                             else
                                pco = mod(4 + i, 9) + 1;
                             end
                             pcx = xa;
                             pcy = ya;
                             break
                          end
                       end   
                     end
               end
            
               xaa = 0; yaa = 0; xbb = 0; ybb = 0; xcc = 0; ycc = 0;
               
               hist = [];
               hist(1, :) = [pay, pax];
               hist(2, :) = [pby, pbx];
               hist(3, :) = [pcy, pcx];
               hist(4, :) = [y, x];
               stop = 0;
               while ( progress < path_len && ~stop)
                  progress = progress + 1;
                  da = 0; db = 0; dc = 0;
                  if pao ~= 0
                      i = 1;
            
                      cn = 0;
                      for ii = 1:8
                           [t1, ~, ~] = p(app, img, pax, pay, ii);
                           [t2, ~, ~] = p(app, img,pax,pay, ii+1);
                           cn = cn + abs (t1-t2);
                      end
                      cn = cn / 2;
             
                      if cn == 1 || cn == 3
                         stop=1;
                      end
            
                      while  i < 9 && da == 0
            
                         [ta, xa, ya] = p(app, img, pax, pay, i);
                         [tz, ~, ~] = p(app, img, pax, pay, i + 1);
            
                         ind_y = hist(:, 1) == ya;
                         ind_x = find(hist(ind_y, 2) == xa);
                         if numel(ind_x) > 0
                            i = i + 1;
                            continue
                         end
            
                         if ta > tz && (xa ~=x || xa~=y)
                            pax = xa;
                            pay = ya;
                            hist(size(hist, 1) + 1, :) = [pay, pax];
                            da = 1;
                            xaa = xa;
                            yaa = ya;
                         end
                         i = i + 1;
             
                      end
                      if da == 0 
                         break
                      end
                  end
            
                  if pbo ~= 0 && ~stop
            
                      cn = 0;
                      for ii = 1:8
                           [t1, ~, ~] = p(app, img, pbx, pby, ii);
                           [t2, ~, ~] = p(app, img, pbx, pby, ii+1);
                           cn = cn + abs (t1 - t2);
                      end
                      cn = cn / 2;
             
                      if cn == 1 || cn == 3
                         stop = 1;
                      end
            
                      i = 1;
            
                      while  i < 9 && db == 0
             
                         [ta, xa, ya] = p(app, img, pbx, pby, i);
                         [tz, ~, ~] = p(app, img, pbx, pby, i + 1);
            
                         ind_y = hist(:, 1) == ya;
                         ind_x = find(hist(ind_y, 2) == xa);
                         if numel(ind_x) > 0
                            i = i + 1;
                            continue
                         end
            
                         if ta > tz && (xa ~=x || xa~=y)
                            pbx = xa;
                            pby = ya;
                            hist(size(hist, 1) +1, :)=[pby, pbx];
                            db = 1;
                            xbb = xa;                                                            
                            ybb = ya;                                                            
                         end
                         i = i + 1;
             
                      end
                  end
            
                  if pco ~= 0 && ~stop
            
                      cn = 0;
                      for ii = 1:8
                           [t1, ~, ~] = p(app, img,pcx, pcy, ii);
                           [t2, ~, ~] = p(app, img, pcx, pcy, ii+1);
                           cn = cn + abs (t1-t2);
                      end
                      cn = cn / 2;
             
                      if cn == 1 || cn == 3
                         stop = 1;
                      end
            
            
                      i = 1;
                      while  i < 9 && dc == 0
              
                         [ta, xa, ya] = p(app, img, pcx, pcy, i);
                         [tz, ~, ~] = p(app, img, pcx, pcy, i + 1);
            
                         ind_y = hist(:, 1) == ya;
                         ind_x = find(hist(ind_y, 2) == xa);
                         if numel(ind_x) > 0
                            i = i + 1;
                            continue
                         end
            
                         if ta > tz && (xa ~=x || xa~=y)
                            pcx = xa;
                            pcy = ya;
                            hist(size(hist, 1) + 1, :)=[pcy, pcx];
                            dc = 1;
                            xcc = xa;                                                            
                            ycc = ya;                                                            
                         end
                         i = i + 1;
                       end
                  end
               end
            
               d1 = sqrt(pdist2([xaa yaa], [xbb ybb], 'squaredeuclidean'));
               d2 = sqrt(pdist2([xaa yaa], [xcc ycc], 'squaredeuclidean'));
               d3 = sqrt(pdist2([xaa yaa], [xbb ybb], 'squaredeuclidean'));
            
            
               if d1 >= d3 &&  d2 >= d3
                 sx = xaa;
                 sy = yaa;
               elseif d1 >= d2 && d3 >= d2
                 sx = xbb;
                 sy = ybb;
               elseif d3 >= d1 && d2 >= d1   
                 sx = xcc;
                 sy = ycc;
               else
                 pause
               end
            
            
               d1 = sqrt(pdist2([xaa yaa], [core_x core_y], ...
                   'squaredeuclidean'));
               d2 = sqrt(pdist2([xbb ybb], [core_x core_y], ...
                   'squaredeuclidean'));
               d3 = sqrt(pdist2([xcc ycc], [core_x core_y], ...
                   'squaredeuclidean'));
            
               qx=0;
               qy=0;
               if d1 >= d2 &&  d1 >= d3
                 qx = xaa;
                 qy = yaa;
               elseif d2 >= d3 && d2 >= d1
                 qx = xbb;
                 qy = ybb;
               elseif d3 >= d2 && d3 >= d1
                 qx = xcc;
                 qy = ycc;
               else
                 pause
               end

                  angle = mod(atan2(y - sy, sx - x), 2 * pi);

                  if qx == sx && qy == sy
                     res = 3;
                  else 
                     res = 3;
                  end
        end
        
        function [ret, combined] = extractfeatures(app, im, skelim, ...
                mask, dmask, binim)
            % taken from Vahid K. Alilou
            % mask
            block_size_c = 24; YA = 0; YB = 0; XA = 0; XB = 0;
            method = - 1; core_y = 0; core_x = 0; core_val = 0; lc = 0;
            o_image1 = sin(dmask); o_image1(mask == 0) = 1;
        
            lower_t = 0.1;
            [v, y] = min(dmask);
            [dt1, x] = min(v);
            delta1_y = y(x) * block_size_c / 2; 
            delta1_x = x * block_size_c / 2;
        
            v(x) = 255; v(x+1) = 255;
            [dt2, x] = min(v);
            delta2_y = y(x) * block_size_c / 2; 
            delta2_x = x * block_size_c / 2;
        
            v(x) = 255; v(x + 1) = 255;
            [dt3, x] = min(v);
            delta3_y = y(x) * block_size_c / 2; 
            delta3_x = x * block_size_c / 2;
        
            db=60;
            if dt1 < 1 && delta1_y+db < core_y && delta1_y > 15 || ...
                dt2 < 1 && delta2_y+db < core_y && delta2_y > 15 ||...
                dt3 < 1 && delta3_y+db < core_y && delta3_y > 15 
                core_val = 255;
            end
            for y = 10:size(o_image1, 1) - 10
                for x = 10:size(o_image1, 2) - 10
                    s1 = 0; t = 10; %few of bad cores here 
                    if y < 50 && x > 250
                       t = 11; 
                    end
                    if y > 38
                       yt = 20;
                    else
                       yt = 5;
                    end
                    if lc > 0.41 && (core_y + 60 < y)
                       break;
                    end
                    if mask(y, x) == 0 || mask(max(y - t, 1), x) ...
                        ==0 || mask(y, min(x + t, ...
                        size(o_image1, 2))) ==0 || ...
                        mask(y, max(x - t, 1)) == 0 || ...
                        mask(max(y - t, 1), ...
                        min(x + t, size(o_image1, 2))) ...
                        == 0 || mask(max(y - t, 1), ...
                        max(x - t, 1))==0 || o_image1(y, x) ...
                        < lc || o_image1(y, x) < 0.1 
                       continue
                    end  
                    if dt1 < 1 && delta1_y+db < y && delta1_y > 15 ...
                        || dt2 < 1 && delta2_y+db < y && delta2_y ...
                        > 15 || dt3 < 1 && delta3_y+db < y ...
                        && delta3_y > 15 
                       continue
                    end
                    test_m = min(o_image1(1:y - yt, max((x - 10) ...
                        ,1):min(x + 10, size (o_image1, 2)) ));
                    if numel(test_m) > 0 && min(test_m) >= 0.17
                       continue
                    end 
                    for a = y:y + 2 
                        for b = x:x + 1
                            s1 = s1 + o_image1(a, b);    
                        end
                    end
                    s1 = s1 / 6; s2 = []; i = 1;
                    for a = y - 3:y - 1
                        for b = x:x + 1
                            s2(i) = o_image1(a, b);    
                            i = i + 1;
                        end
                    end
                    if min(s2) < lower_t
                       s2 = sum(s2) / 6;       
                    else 
                       s2 = s1;
                    end
                    s3 = []; i = 1;
                    for a = y:y + 2 
                        for b = x + 2:x + 3
                            s3(i) = o_image1(a, b);    
                            i = i + 1;
                        end
                    end
                    if min(s3) < lower_t
                       s3 = sum(s3) / 6;       
                    else 
                       s3 = s1;
                    end
                    s4 = []; i = 1;
                    for a = y:y + 2 
                        for b = x - 2:x - 1
                            s4(i) = o_image1(a, b);    
                            i = i + 1;
                        end
                    end
                    if min(s4) < lower_t
                       s4 = sum(s4) / 6;       
                    else 
                       s4 = s1;
                    end
                    s5 = [];
                    i = 1;
                    for a = y - 3:y - 1 
                        for b = x - 2:x - 1
                            s5(i) = o_image1(a, b);    
                            i = i + 1;
                        end
                    end
                    if min(s5) < lower_t
                       s5 = sum(s5) / 6;       
                    else 
                      s5 = s1;
                    end
                    s6 = []; i = 1;
                    for a = y - 3:y - 1 
                        for b = x + 2:x + 3
                            s6(i) = o_image1(a, b);    
                            i = i + 1;
                        end
                    end
                    if min(s6) < lower_t
                      s6 = sum(s6) / 6;       
                    else 
                      s6 = s1;
                    end
                    if s1 - s2 > core_val
                       core_val = s1 - s2;
                       core_x = x;
                       core_y = y;
                       lc = o_image1(y, x);
                       method = 1;
                    end 
                    if s1-s3 > core_val
                       core_val = s1 - s3;
                       core_x = x;
                       core_y = y;
                       lc = o_image1(y, x);
                       method = 2;
                    end 
                    if x < 300 && s1 - s4 > core_val
                       core_val = s1 - s4;
                       core_x = x;
                       core_y = y;
                       lc = o_image1(y, x);
                       method = 3;
                    end 
                    if x < 300 && s1 - s5 > core_val
                       core_val = s1 - s5;
                       core_x = x;
                       core_y = y;
                       lc = o_image1(y, x);
                       method = 4;
                    end 
                    if s1-s6 > core_val
                       core_val = s1 - s6;
                       core_x = x;
                       core_y = y;
                       lc = o_image1(y, x);
                       method = 5;
                    end 
                end
            end
            if core_y > 37
               yt = 20;
            else
               yt = 5;
            end
            test_smooth = 100;
            if core_y > 0
               test_smooth= sum(sum(o_image1(core_y - yt - 5: ...
                   core_y - yt + 5, core_x - 5:core_x + 5)));
            end
            if lc > 0.41 && (test_smooth < 109.5 && method~=2 || ...
                    test_smooth < 100) 
               start_t = 0;
               core_val = 1 / (core_val + 1);
            else
               core_x = 0;
               core_y = 0;
               core_val = 255;
            end
            mask = dmask; path_len = 45;
        
        % find minutiae
            minu_count = 1;
            minutiae(minu_count, :) = [0, 0, 0, 0, 0, 1];
            min_path_index = [];
            % loop through image and find minutiae, ignore certain 
            % pixels for border
            for y = 20:size(im, 1) - 14
                for x = 21:size(im, 2) - 21
                    if (skelim(y, x) == 1) % only continue if 
                        % pixel is white
                        % calculate CN from Raymond Thai
                        CN = 0; sx=0; sy=0;
                        for i = 1:8
                          t1 = p(app, skelim, x, y, i);
                          t2 = p(app, skelim, x, y, i+1);
                          CN = CN + abs (t1-t2);
                        end   
                        CN = CN / 2;
                        if ((CN == 1) || (CN == 3))
                           skip = 0;
                           for i = y - 5:y + 5
                                for j = x - 5:x + 5
                                  if i > 0 && j > 0 && mask(i, j) == 0   
                                     skip = 1;
                                  end
                                end
                           end
                           if skip == 1
                              continue;
                           end
                           t_a = [];
                           c = 0;
                           for e = y - 1:y + 1
                               for f = x - 1:x + 1
                                   c = c + 1;
                                   t_a(c) = dmask(e, f);
                               end
                           end 
                           m_o = median(t_a); m_f = 0; 
                           if CN == 3
                              [CN, prog, sx, sy, ang]= ...
                              test_bifurcation(app, skelim, x, y, ...
                              m_o, core_x, core_y);
                              if prog < 3
                                 continue
                              end
                              if ang < pi 
                                 m_o = mod(m_o + pi, 2 * pi);
                              end
                           else
                              progress = 0;
                              xx = x; yy = y; pao = - 1; pos=0;
                              while progress < 15 && xx > 1 && yy > ...
                                  1 && yy < size(im, 1) && ...
                                  xx < size(im, 2) && pos > - 1
                                    pos= - 1;
                                    for g = 1:8
                                        [ta, xa, ya] = p(app, ...
                                            skelim, xx, yy, g);
                                        [tb, ~, ~] = p(app, skelim, ...
                                            xx, yy, g + 1);
                                        if (ta > tb)  && pos == - 1 ...
                                            && g ~= pao
                                           pos = ta;
                                           if g < 5
                                              pao = 4 + g;
                                           else
                                              pao = mod(4 + g, 9) + 1;
                                           end
                                           xx = xa; yy = ya;
                                        end 
                                    end
                                    progress = progress + 1;
                              end
                              if progress < 10
                                 continue
                              end
                              if mod(atan2(y - yy, xx - x), ...
                                      2 * pi) > pi
                                 m_o = m_o + pi;
                              end
                           end               
                           minutiae(minu_count, :) = [ x, y, CN, ...
                               m_o, m_f, 1];
                           min_path_index(minu_count, :) = [sx sy];
                           minu_count = minu_count + 1;
                        end
                    end % if pixel white
                end % for y
            end % for x
            % filter false minutiae
            minu_count = minu_count - 1;
            t_minutiae = [];
            t_minu_count = 1;
            t_mpi = [];
            for i=1:minu_count
                X = minutiae(i,1); Y = minutiae(i, 2);
                rc = 0; 
                for y = max(Y - 2, 1):min(Y + 2, size(binim, 1)) 
                    if rc > 0
                       break
                    end
                    for x = max(X - 2, 1):min(X + 2, size(binim, 2))  
                        if mask(y,x) == 0
                           rc = rc + 1;   
                           break
                        end
                    end
                end
                if rc > 0 
                     continue;
                else
                    t_minutiae(t_minu_count, :) = minutiae(i, :);
                    t_mpi(t_minu_count, :) = min_path_index(i, :);
                    t_minu_count = t_minu_count + 1;
                end
            end
            minutiae = t_minutiae;
            min_path_index = t_mpi;
            minu_count = size(minutiae, 1);
            t_minu_count = 1; t_minutiae = [];
            dist_m = pdist2(minutiae(:, 1:2), minutiae(:, 1:2), ...
                'squaredeuclidean');
            dist_test = 49;
            for i = 1:minu_count
              reject_flag = 0;
              P_x = minutiae(i, 1); P_y = minutiae(i, 2);
              for j = i + 1 : minu_count
                if dist_m(i, j) <= dist_test
                   reject_flag = 1;
                end
              end
              if reject_flag == 0 && mask(P_y, P_x) > 0
                 reverse_p = 0;  
                 if min_path_index(i, 1) == 0
                   x = P_x;
                   y = P_y; 
                 else
                   x =  min_path_index(i, 1);
                   y =  min_path_index(i, 2); 
                 end
                 p1x = P_x; p1y = P_y;
                 x1 = x; y1 = y;
                 iter = 0;
                 for m = 1:path_len
                       iter = iter + 1;
                       cn = 0;
                       for ii = 1:8
                           t1 = p(app, skelim, x1, y1, ii);
                           t2 = p(app, skelim, x1, y1, ii+1);
                           cn = cn + abs (t1 - t2);
                       end
                       cn = cn / 2;
                       if cn ~= 3 && cn ~= 4 || m == 1
                          for n = 1:8
                              if reverse_p == 0 || iter > 1 
                                 [ta, xa, ya] = p(app, skelim, x1, ...
                                     y1, n);
                              else
                                 [ta, xa, ya] = p(app, skelim, x1, ...
                                     y1, 9-n);
                              end
                              if ta == 1 && (xa ~= p1x || ...
                                      ya ~= p1y) && (xa ~= x || ...
                                      ya ~= y)
                                 p1x = x1; p1y = y1;
                                 x1 = xa; y1 = ya;
                                 break;
                              end
                          end
                       end
                 end
                 t_minutiae(t_minu_count, :) = minutiae(i, :);
                 t_minu_count = t_minu_count + 1;
              end
            end
            minutiae = t_minutiae;
            minu_count = t_minu_count - 1;
            tmpvec1 = size(im, 1).*ones(minu_count, 1);    
            tmpvec2 = ones(minu_count, 1);                 
            minutiae_for_sc = [minutiae(:, 1) / size(im, 2) ...
                (tmpvec1 - minutiae(:, 2) + tmpvec2) / size(im, 1)];  
            dist_m = sqrt(pdist2(minutiae_for_sc(:, 1:2), ...
                minutiae_for_sc(:, 1:2), 'squaredeuclidean'));
            for i = 1:minu_count
              [~, ind] = sort(dist_m(i, :));
              for j = 1:minu_count
                if  dist_m(i, ind(j)) == 0
                   continue
                end
                theta_t = mod(atan2(minutiae(i, 2) - ...
                    minutiae(ind(j), 2), minutiae(i, 1) - ...
                    minutiae(ind(j), 1)), 2*pi);
                ridge_count = 0;
                p_y = minutiae(i, 2);  p_x = minutiae(i, 1); 
                t_x = 0; t_y = 0;
                current = 1; radius = 1;
                while p_y ~= minutiae(ind(j), 2)
                    if skelim(p_y, p_x) > 0  && current == 0 && ...
                        (t_x ~= p_x || t_y ~= p_y)
                       current = 1;
                       ridge_count = ridge_count + 1;
                    else
                         if skelim(p_y, p_x) == 0
                            current = 0;
                         end
                    end    
                    t_x = p_x; t_y = p_y;
                    p_x = round(minutiae(i, 1) - radius*cos(theta_t)); 
                    p_y = round(minutiae(i, 2) - radius*sin(theta_t));
                    radius = radius + 1;
                end
              end
            end
            if core_val < 1
              minutiae(minu_count + 1, :) = [core_x, core_y, 5, ...
                  start_t, 0, 1]; 
              minu_count = minu_count + 1;
            end
            if dt1 < 1
               minutiae(minu_count + 1, :) = [delta1_x, delta1_y, ...
                   7, 0, 1, 1];
               minu_count = minu_count + 1;
            end
            if dt2 < 1       
               minutiae(minu_count + 1, :) = [delta2_x, delta2_y, ...
                   7, 0, 1, 1];
               minu_count = minu_count + 1;
            end
            if dt3 < 1       
               minutiae(minu_count + 1, :) = [delta3_x, delta3_y, ...
                   7, 0, 1, 1];
               minu_count = minu_count + 1;
            end
        
        % return minutiae
                minutiae_image = uint8(zeros(size(im, 1), ...
                    size(im, 2), 3));
                for i = 1:minu_count 
                x1 = minutiae(i, 1); y1 = minutiae(i, 2);
                if minutiae(i, 3) == 1                    % termination
                   if minutiae(i, 4) > pi
                    for k = y1 - 2: y1 + 2
                      for l = x1 - 2: x1 + 2
                        minutiae_image(k, l,:) = [255, 0, 0];  % red
                      end
                    end
                   else
                    for k = y1 - 2: y1 + 2
                      for l = x1 - 2: x1 + 2
                        minutiae_image(k, l,:) = [205, 100, 100]; 
                        % pale red
                      end
                    end
                   end
                elseif minutiae(i, 3) == 2
                    for k = y1 - 2: y1 + 2
                      for l = x1 - 2: x1 + 2
                        minutiae_image(k, l,:) = [255, 0, 255]; % pink
                      end
                    end
                elseif minutiae(i, 3) == 3                % bifurcation
                  if minutiae(i, 4) > pi
                    for k = y1 - 2: y1 + 2
                      for l = x1 - 2: x1 + 2
                        minutiae_image(k, l, :) = [0, 0, 255]; % blue
                      end
                    end
                  else 
                    for k = y1 - 2: y1 + 2
                      for l = x1 - 2: x1 + 2
                        minutiae_image(k, l, :) = [255, 0, 255]; % pink
                      end
                    end
                  end
                elseif minutiae(i, 3) == 5
                    for k = y1 - 4: y1 + 4
                      for l = x1 - 4: x1 + 4
                        minutiae_image(k, l,:) = [0, 255, 0]; % green
                      end
                    end
                elseif minutiae(i, 3) > 5
                    for k = y1 - 2: y1 + 2
                      for l = x1 - 2: x1 + 2
                          minutiae_image(k, l,:) = [128, 128, 0]; 
                          % gold for delta
                      end
                    end
                end   
                end
                combined = uint8(minutiae_image);    
                for x = 1:size(binim, 2)
                for y = 1:size(binim, 1)
                    if mask(y, x) == 0
                        combined(y, x, :) = [0, 0, 0]; % black
                        continue
                    end
                    if (skelim(y, x))
                        combined(y, x, :) = [255, 255, 255]; % white
                    else
                        combined(y, x, :) = [0, 0, 0];
                    end % end if
                    if ((minutiae_image(y, x, 3) ~= 0) || ...
                            (minutiae_image(y, x, 1) ~= 0) ) || ...
                        (minutiae_image(y, x, 2) ~= 0)
                        combined(y, x, :) = minutiae_image(y, x, :);
                    end
                end % end for y
                end % end for x
                if core_val < 1 && YA > 0
                    for k = YA - 2: YA + 2
                      for l = XA - 2: XA + 2
                         combined(k, l, :) = [20, 255, 250]; % aqua
                      end
                    end
                    for k = YB - 2: YB + 2
                      for l = XB - 2: XB + 2
                         combined(k, l, :) = [20, 255, 250]; % aqua
                      end
                    end
                end
            ret = minutiae;
        end

    end
    
    methods (Access = public)

        function enhancement_performance(~, binim, im)
            nw_binim = double(binim);
            og_binim = imbinarize(im, 'adaptive');
            og_binim = double(og_binim);
            [binpeaksnr, binsnr] = psnr(nw_binim, og_binim);
            [binerr] = immse(nw_binim, og_binim);
            fprintf(['Binary image enhancement results: \npeaksnr = %f' ...
                ' \nsnr = %f \nerr = %f \n'], binpeaksnr, binsnr, binerr);
            
            nw_skel = bwmorph(nw_binim,'skel', Inf);
            og_skel = bwmorph(og_binim,'skel', Inf);
            nw_skel = double(nw_skel);
            og_skel = double(og_skel);
            [skelpeaksnr, skelsnr] = psnr(nw_skel, og_skel);
            [skelerr] = immse(nw_skel, og_skel);
            fprintf(['\nSkeleton image enhancement results: \npeaksnr' ...
                ' = %f \nsnr = %f \nerr = %f \n'], skelpeaksnr, ...
                skelsnr, skelerr);
        end

        function [minutiae] = feature_reduction(~, minutiae)
            [~, ~, ~, ~, explained, ~] = pca(minutiae(:,1:5));

            disp(explained)
            
            minutiae = minutiae(:,1:4);
        end
        
        function [a_minutiae, a_combined, a_binim] = authentication(app, im)
            im = mat2gray(im);
            a_mask = segment(app, im, 16, 0.05);

            [a_gx, a_gy, ~, ~] = ridgegradient(app, im, ...
                a_mask, 16, 0.1);

            [a_dmask] = ridgeorient(app, 16, 16, a_gx, a_gy, a_mask);

            [~, a_medianfreq] = ridgefreq(app, im, a_mask, ...
                a_dmask, 36, 5, 5, 100);

            [freq] = a_medianfreq.*a_mask;

            [a_newim] = ridgefilter(app, im, a_dmask, freq, 0.5, 0.5);

            [a_binim] = imbinarize(a_newim, 'adaptive');

            [inv_binim] = (a_binim == 0);

            [a_skelim] = bwmorph(inv_binim,'skel',Inf);
            
            [a_minutiae, a_combined] = extractfeatures(app, im, ...
                a_skelim, a_mask, a_dmask, a_binim);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.tif', 'File Selector');
            [name] = strcat(pathname, filename);
            [app.im] = imread(name);
            [app.im] = mat2gray(app.im);
            imshow(app.im, 'Parent', app.FingerprintImage);
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            cla(app.FingerprintImage)
            [app.mask] = segment(app, app.im, 16, 0.05);
            [app.roi] = double(app.im).*double(app.mask);
            imshow(app.roi, [], 'Parent', app.FingerprintImage)
        end

        % Button pushed function: GradientButton
        function GradientButtonPushed(app, event)
            cla(app.FingerprintImage)
            [app.gx, app.gy, gxmask, gymask] = ridgegradient(app, ...
                app.im, app.mask, 16, 0.1);
            imshow(app.roi, [], 'Parent', app.FingerprintImage);
            hold(app.FingerprintImage, 'on');
            quiver(gxmask, gymask, 'Parent', app.FingerprintImage);
        end

        % Button pushed function: OrientationButton
        function OrientationButtonPushed(app, event)
            cla(app.FingerprintImage)
            [app.dmask] = ridgeorient(app, 16, 16, app.gx, app.gy, ...
                app.mask);
            [app.orientim] = scalemap(app, app.dmask);
            imshow(app.orientim, 'Parent', app.FingerprintImage);
        end

        % Button pushed function: DirectionMapButton
        function DirectionMapButtonPushed(app, event)
            cla(app.FingerprintImage)
            [x, y, u, v] = directionmap(app, app.dmask, 15);
            imshow(app.roi, [], 'Parent', app.FingerprintImage);
            hold(app.FingerprintImage, 'on');
            quiver(x, y, u, v, 0, '.', 'linewidth', 2, 'Parent', ...
                app.FingerprintImage);
        end

        % Button pushed function: RidgeFrequencyButton
        function RidgeFrequencyButtonPushed(app, event)
            cla(app.FingerprintImage)
            [fmask, app.medianfreq] = ridgefreq(app, app.im, app.mask, ...
                app.dmask, 36, 5, 5, 100);
            [freqim] = scalemap(app, fmask);
            imshow(freqim, [], 'Parent', app.FingerprintImage);
        end

        % Button pushed function: GaborFilteringButton
        function GaborFilteringButtonPushed(app, event)
            cla(app.FingerprintImage)
            [freq] = app.medianfreq.*app.mask;
            [app.newim] = ridgefilter(app, app.im, app.dmask, freq, 0.5 ...
                , 0.5);
            imshow(app.newim, 'Parent', app.FingerprintImage);
        end

        % Button pushed function: BinarizationButton
        function BinarizationButtonPushed(app, event)
            cla(app.FingerprintImage)
            [app.binim] = imbinarize(app.newim, 'adaptive');
            imshow(app.binim, 'Parent', app.FingerprintImage);
        end

        % Button pushed function: ThinningButton
        function ThinningButtonPushed(app, event)
            cla(app.FingerprintImage)
            [inv_binim] = (app.binim == 0);
            [app.skelim] = bwmorph(inv_binim,'skel', Inf);
            imshow(app.skelim, 'Parent', app.FingerprintImage);
            fprintf('Enroll Enhancement - \n')
            enhancement_performance(app, app.binim, app.im);
        end

        % Button pushed function: EnrollButton
        function EnrollButtonPushed(app, event)
            cla(app.FingerprintImage)
            [minutiae, app.combined] = extractfeatures(app, app.im, ...
                app.skelim, app.mask, app.dmask, app.binim);
            fprintf('\nEnrolled Percent of variability:\n');
            [reduced_minutiae] = feature_reduction(app, minutiae);
            imshow(app.combined, 'Parent', app.FingerprintImage); 
            [app.location] = uigetdir;  
            save(fullfile(app.location, 'Enrolled_Fingerprint'), ...
                'reduced_minutiae');
            [app.auth_score] = Authentication_And_Score(app, app.combined);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {214, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel_GridLayout = uigridlayout(app.LeftPanel);
            app.FingerprinttoenhancePanel = uipanel(app.LeftPanel_GridLayout);
            app.FingerprinttoenhancePanel.Title = 'Fingerprint to enhance';
            app.Fingerprinttoenhance_GridLayout = uigridlayout(app.FingerprinttoenhancePanel);
            app.SelectImageButton = uibutton(app.Fingerprinttoenhance_GridLayout, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.PreprocessingPanel = uipanel(app.LeftPanel_GridLayout);
            app.PreprocessingPanel.Title = 'Pre-processing';
            app.Preprocessing_GridLayout = uigridlayout(app.PreprocessingPanel);
            app.Label_8 = uilabel(app.Preprocessing_GridLayout);
            app.Label_8.Text = '8).';
            app.Label_7 = uilabel(app.Preprocessing_GridLayout);
            app.Label_7.Text = '7).';
            app.Label_6 = uilabel(app.Preprocessing_GridLayout);
            app.Label_6.Text = '6).';
            app.Label_5 = uilabel(app.Preprocessing_GridLayout);
            app.Label_5.Text = '5).';
            app.Label_4 = uilabel(app.Preprocessing_GridLayout);
            app.Label_4.Text = '4).';
            app.Label_3 = uilabel(app.Preprocessing_GridLayout);
            app.Label_3.Text = '3).';
            app.Label_2 = uilabel(app.Preprocessing_GridLayout);
            app.Label_2.Text = '2).';
            app.Label_1 = uilabel(app.Preprocessing_GridLayout);
            app.Label_1.Text = '1).';
            app.ThinningButton = uibutton(app.Preprocessing_GridLayout, 'push');
            app.ThinningButton.ButtonPushedFcn = createCallbackFcn(app, @ThinningButtonPushed, true);
            app.ThinningButton.Text = {'Thinning'; ''};
            app.BinarizationButton = uibutton(app.Preprocessing_GridLayout, 'push');
            app.BinarizationButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizationButtonPushed, true);
            app.BinarizationButton.Text = 'Binarization';
            app.GaborFilteringButton = uibutton(app.Preprocessing_GridLayout, 'push');
            app.GaborFilteringButton.ButtonPushedFcn = createCallbackFcn(app, @GaborFilteringButtonPushed, true);
            app.GaborFilteringButton.Text = 'Gabor Filtering';
            app.RidgeFrequencyButton = uibutton(app.Preprocessing_GridLayout, 'push');
            app.RidgeFrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @RidgeFrequencyButtonPushed, true);
            app.RidgeFrequencyButton.Text = 'Ridge Frequency';
            app.DirectionMapButton = uibutton(app.Preprocessing_GridLayout, 'push');
            app.DirectionMapButton.ButtonPushedFcn = createCallbackFcn(app, @DirectionMapButtonPushed, true);
            app.DirectionMapButton.Text = 'Direction Map';
            app.OrientationButton = uibutton(app.Preprocessing_GridLayout, 'push');
            app.OrientationButton.ButtonPushedFcn = createCallbackFcn(app, @OrientationButtonPushed, true);
            app.OrientationButton.Text = 'Orientation';
            app.GradientButton = uibutton(app.Preprocessing_GridLayout, 'push');
            app.GradientButton.ButtonPushedFcn = createCallbackFcn(app, @GradientButtonPushed, true);
            app.GradientButton.Text = 'Gradient';
            app.SegmentationButton = uibutton(app.Preprocessing_GridLayout, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segmentation';
            app.EnrollmentPanel = uipanel(app.LeftPanel_GridLayout);
            app.EnrollmentPanel.Title = 'Enrollment';
            app.Enrollment_GridLayout = uigridlayout(app.EnrollmentPanel);
            app.EnrollButton = uibutton(app.Enrollment_GridLayout, 'push');
            app.EnrollButton.ButtonPushedFcn = createCallbackFcn(app, @EnrollButtonPushed, true);
            app.EnrollButton.Text = 'Enroll';
            app.Label = uilabel(app.LeftPanel_GridLayout);
            app.Label.Text = {''; ''; 'Guide:'; ''; 'First select a fingerprint image to enhance by clicking the "Select Image" button on the "Fingerprint to enhance" panel.'; ''; 'Next select all of the pre-processing step buttons 1-8 on the "Pre-processing" panel to enhance the image.'; ''; 'Once all pre-processing steps are complete the image is now ready for enrollment on to the database.'; ''; 'Now select the "Enroll" button on the "Enrollment" panel. This will extract and add the minutiae from the enhanced fingerprint image to the database.'; ''; 'Once you have finished adding the selected image to the database you will be redirected to the "Authentication & Score" window. Where there are further instructions.'};
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel_GridLayout = uigridlayout(app.RightPanel);
            app.FingerprintImage = uiaxes(app.RightPanel_GridLayout);
            app.FingerprintImage.XTick = [];
            app.FingerprintImage.XTickLabel = '';
            app.FingerprintImage.YTick = [];
            app.FingerprintImage.ZTick = [];
            app.PreprocessingEnrollmentLabel = uilabel(app.RightPanel_GridLayout);
            app.PreprocessingEnrollmentLabel.Text = 'Pre-processing & Enrollment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing_And_Enrollment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end